classdef NonTeleReconApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ReconstructTab                 matlab.ui.container.Tab
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        UnwrapButton                   matlab.ui.control.Button
        ColormapDropDown               matlab.ui.control.DropDown
        BusyLamp                       matlab.ui.control.Lamp
        BusyLampLabel                  matlab.ui.control.Label
        NSlider                        matlab.ui.control.Slider
        NSliderLabel                   matlab.ui.control.Label
        MSlider                        matlab.ui.control.Slider
        MSliderLabel                   matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        CostFunctionDropDown           matlab.ui.control.DropDown
        CostFunctionLabel              matlab.ui.control.Label
        ChangeSignofCurvatureCheckBox  matlab.ui.control.CheckBox
        EnableSwitch                   matlab.ui.control.ToggleSwitch
        EnableLabel                    matlab.ui.control.Label
        FineTuneghButton               matlab.ui.control.StateButton
        RunButton                      matlab.ui.control.Button
        FilterMNButton                 matlab.ui.control.Button
        Filter1termButton              matlab.ui.control.Button
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        PixelSizeXEditField            matlab.ui.control.NumericEditField
        PixelSizeXEditFieldLabel       matlab.ui.control.Label
        WavelengthEditField            matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        LoadHoloDropDown               matlab.ui.control.DropDown
        Step1LoadHologramLabel         matlab.ui.control.Label
        SaveTab                        matlab.ui.container.Tab
        UnwrappedPhaseSwitch           matlab.ui.control.Switch
        UnwrappedPhaseSwitchLabel      matlab.ui.control.Label
        MinimizedPhaseSwitch           matlab.ui.control.Switch
        MinimizedPhaseSwitchLabel      matlab.ui.control.Label
        SaveFolderNameLabel            matlab.ui.control.Label
        SaveFileNameEditField          matlab.ui.control.EditField
        SaveFileNameEditField_2Label   matlab.ui.control.Label
        SaveFolderNameEditField        matlab.ui.control.EditField
        ExportReconButton              matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public) %properties corresponding to reconstruction algorithm
      lambda double {mustBeNonnegative} % wavelength
      k double {mustBeNumeric}          % wave number
      dx {mustBeNumeric}                % pixel size in x
      dy {mustBeNumeric}                % pixel size in y
      g {mustBeNumeric}                 % x coordinate of spherical center
      h {mustBeNumeric}                 % y coordinate of spherical center
      m {mustBeNumeric}                 % x coordinate size of +1 term
      n {mustBeNumeric}                 % y coordinate size of +1 term
      p {mustBeNumeric}                 % x coordinate position of +1 term
      q {mustBeNumeric}                 % y coordinate position of +1 term
      seedCx double{mustBeNumeric}      % value of Cx prior to minimization
      seedCy double{mustBeNumeric}      % value of Cy prior to minimization
      Cx double{mustBeNumeric}          % radius of curvature in x
      Cy double{mustBeNumeric}          % radius of curvature in y
      M {mustBeNumeric}                 % size(holo) in X
      N {mustBeNumeric}                 % size(holo) in Y
      X                                 % meshgrid X
      Y                                 % meshgrid Y
      filename                          % name of read file 
      phi_spherical                     % spherical aberration created using Anon func and before putting in exp(-j*phi)
      phase_mask                        % phase mask created w/ phi_spherical that is multiplied by holocompensate
      corrected_image                   % image corrected (i.e. Min/Kemper) before minimization
      holoCompensate                    % +1 term center and IFT'd. use angle(holoCompensate) to show
      holo                              % hologram
      ft_holo                           % fourier transform of holo
      crop                              % rect selection of +1 to be used for reconstruction
      plus_coor                         % coordinates of +1 term
      plus1                             % rect of +1 term
      bounding_box_gh                   % rect of spherical aberration used to find G & H
      min_alg                           % minimization algorithm used (GA+PS, PS, SA, etc)
      cost_fn                           % cost function used for minimization (BIN or SD) 
      min_time                          % time it took for reconstruction
      cmap                              % colormap used for figures
      minimized_image                   % image obtained via minimization
      unwrap_image                      % Unwrapped phase image
    end
    methods (Access = private) %methods having to do with UI
        function flag = InitializeDropDownMenu(app) % function that populates Drop down menu
            f = [dir("*.tiff");dir("*.tif")]; % currently only search tif and tiff extensions but more can be added
            flag = ~isempty(f); 
            if flag % only attempts to populate drop down if folder has images
                items = cell(1,length(f));
                for i = 1:length(f)
                    items{i} = (f(i).name);
                end
                app.LoadHoloDropDown.Items = items;
                app.LoadHoloDropDown.Value = f(1).name; % defaults to first value
            end
        end
        function UpdateAll(app) % Updates Ui for new input hologram
            InitializeProperties(app,string(app.LoadHoloDropDown.Value),...
                app.WavelengthEditField.Value, app.PixelSizeXEditField.Value,...
                app.YEditField.Value);
            UpdateUI_Axes(app,app.holo,app.UIAxes,'gray')
            UpdateUI_Axes(app,log(abs(app.ft_holo)).^2,app.UIAxes_3,'gray')
        end
        function UpdateUI_Axes(~,image,axes,cmap)
            imagesc(axes, image),...
            colormap(axes,cmap),axis(axes, 'tight');
        end
        function InitializeMSlider(app) % Initialize M Slider
            m = app.plus1(3); 
            r = .5; % 50% range + and - m
            app.MSlider.Limits = [m-r*m, m+r*m];
            app.MSlider.Value = m;
        end
        function InitializeNSlider(app) % Initialize N Slider
            n = app.plus1(4);
            r = .5; % 50% range + and - n
            app.NSlider.Limits =[n-r*n, n+r*n];
            app.NSlider.Value = n;
        end
    end
    methods (Access = private)%methods having to do with recon alg
        function main(app,varargin) % 6 step reconstruction algorithm for non-telecentric algortihm
            app.unwrap_image = []; %if anything changes empty the phase wrap image
            app.UnwrapButton.Text = "Unwrap";
            get_plus1_manually(app) % get size and postion of +1 term by user
            filter_center_plus1(app) % compensates linear tilt w/ reference wave
            if app.FineTuneghButton.Value == 0 % automatically finds the center of the spherical aberration
                bw = binarize_compensated_plus1(app);
                get_g_and_h(app, bw);
            end
            manual_recon(app); % Uses parameters to compensate spherical aberration
            if nargin == 1 % update both axis 2 and 4
                UpdateUI_Axes(app,angle(app.holoCompensate),...
                    app.UIAxes_2,app.cmap)
                UpdateUI_Axes(app,angle(app.corrected_image),...
                    app.UIAxes_4,app.cmap)
            end
            if nargin>1 && varargin{1} == 1 % only update axis 2
                UpdateUI_Axes(app,angle(app.holoCompensate),...
                    app.UIAxes_2,app.cmap)
            end
            if nargin>2 && varargin{2} == 1 % only update axis 4
                UpdateUI_Axes(app,angle(app.corrected_image),...
                    app.UIAxes_4,app.cmap)
            end
            if nargin>3 && varargin{3} == 1 % update axis 4 after minimization
                tic
                run_2d_minimization(app)
                app.min_time = toc;
                disp(app.min_time)
                UpdateUI_Axes(app,angle(app.minimized_image),...
                    app.UIAxes_4,app.cmap)
            end            
        end
        function export(app,savefile) % save output structure
              saved.lambda = app.lambda;
              saved.k = app.k;
              saved.dx = app.dx;
              saved.dy = app.dy;
              saved.g = app.g;
              saved.h = app.h;
              saved.m = app.m;
              saved.n = app.n;
              saved.p = app.p;
              saved.q = app.q;
              saved.Cx = app.Cx;
              saved.Cy = app.Cy;
              saved.M = app.M;
              saved.N = app.N;
              saved.X = app.X;
              saved.Y = app.Y;
              saved.filename = app.filename;
              saved.phi_spherical = app.phi_spherical;
              saved.phase_mask = app.phase_mask;
              saved.corrected_image = app.corrected_image;
              saved.holoCompensate = app.holoCompensate;
              saved.holo = app.holo;
              saved.ft_holo= app.ft_holo;
              saved.crop= app.crop;
              saved.plus_coor= app.plus_coor;
              saved.plus1= app.plus1;
              saved.bounding_box_gh= app.bounding_box_gh;
              saved.savefile = savefile;
              
              if app.MinimizedPhaseSwitch.Value == "On"
                saved.seedCx = app.seedCx;
                saved.seedCy = app.seedCy;
                saved.min_alg = app.min_alg;
                saved.minimized_image = app.minimized_image;
              end
              if app.UnwrappedPhaseSwitch.Value == "Off"
                saved.unwrap_image = app.unwrap_image;
              end
              save(savefile,"saved")
        end
        function InitializeProperties(app,varargin) % initialize most properties of algorithm
           % Disable the following UI components until prerequisites are met
           app.FineTuneghButton.Enable = 0;
           app.FilterMNButton.Enable = 0;
           app.MSlider.Enable = 0; app.NSlider.Enable = 0;
           app.ChangeSignofCurvatureCheckBox.Enable = 0;
           app.EnableSwitch.Enable = 0;
           app.UnwrapButton.Enable = 0;
           % Load Hologram Parameters
           app.filename = varargin{1}; % second input is filename
           app.cmap = app.ColormapDropDown.Value; % get cmap from dropdown
           savename = string(app.filename).split("."); % drop extension for savename 
           savename = savename(1)+".mat"; % append new extension
           app.SaveFileNameEditField.Value = savename; % set save name
           app.SaveFolderNameEditField.Value = "save"; % set default save foldler
           app.lambda = varargin{2}; % get wavelength from third input
           app.k = 2*pi/app.lambda; % calculate wavenumber
           app.dx = varargin{3};app.dy = varargin{4} ;% inputs 4 and 5 are pixel dimensions
           app.holo = double(imread(app.filename)); % read image selected by drop down menu
           app.holo = app.holo(:,:,1); % only use first channel (grayscale images)
           if nargin>5
                app.crop = varargin{5}; % if there is a 6th input, use as image crop
                app.holo = imcrop(app.holo,app.crop);
           end
           [app.M,app.N] = size(app.holo); % sensor size    
           % get meshgrid for pixel index list. meshgrid take columns and then rows
           [app.X,app.Y] = meshgrid(-app.N/2+1:app.N/2,-app.M/2+1:app.M/2); %switched M(row) and N(col)
           app.ft_holo = FT(app); % get fourier transform
           app.plus1 = [0,0,0,0]; % use as defualt values for +1 term
        end
        function ft = FT(app) % get fourier transform of hologram 
            ft = fftshift(fft2(fftshift(app.holo)));
        end
        function get_plus1_manually(app) % parse out user crop input to useful information
            % crop(1)and(2) is top left coordinate and crop(3)and(4) is dimensions
            app.plus_coor = [app.crop(1)+app.crop(3)/2, app.crop(2)+app.crop(4)/2]; 
            dc_coor = [app.M/2, app.N/2]; % DC coor is half the sensor size
            p_and_q = abs(app.plus_coor - dc_coor); % distance from center to +1 term 
            % values for p,q,m and n (min and kemper paper)
            app.m = app.plus1(3)/2 ;%
            app.n = app.plus1(4)/2 ;%
            app.p = p_and_q(1);
            app.q = p_and_q(2);
            disp("P: "+string(app.p)+" Q: "+string(app.q));
            disp("M: "+string(app.m)+" N: "+string(app.n));
        end
        function filter_center_plus1(app) % compensate tilt aberration
            Filter = zeros(app.M,app.N);
            warning('off','all')
            Filter((app.plus_coor(2) - app.n) :(app.plus_coor(2)+ app.n),...
            (app.plus_coor(1) - app.m) :(app.plus_coor(1)+ app.m))=1;
            FT_FilteringH = app.ft_holo.*Filter;
            
            ThetaXN = asin((app.N/2 - app.plus_coor(1))*app.lambda/(app.N * app.dx));
            ThetaYM = asin((app.M/2 - app.plus_coor(2))*app.lambda/(app.M * app.dy));
            Reference = exp(1i*app.k*(sin(ThetaXN)*app.X*app.dx + sin(ThetaYM)*app.Y*app.dy));

            holo_filtered = fftshift(ifft2(fftshift(FT_FilteringH)));
            app.holoCompensate = holo_filtered.*Reference;
        end
        function get_g_and_h(app, bw) % get center coordinates for spherical aberration
            cc = bwconncomp(bw,4);

            numPixels = cellfun(@numel,cc.PixelIdxList);
            num_of_terms = 3;
            idx_list = zeros(num_of_terms,1);


            for i = 1:num_of_terms
                [~,idx] = max(numPixels);
                idx_list(i) = idx;
                numPixels(idx) = 0;
            end

            for i = 1:length(cc.PixelIdxList)
                if ~(i == idx_list(1) || i == idx_list(2) || i == idx_list(3))
                    bw(cc.PixelIdxList{i}) = 0;
                end
            end

            terms = regionprops(bw,'all');
            [~,best_term_idx] = min([terms.Eccentricity]);
            best_term = terms(best_term_idx).BoundingBox;
            block_center = [best_term(1)+best_term(3)/2, best_term(2)+best_term(4)/2];
            true_center = [app.N/2, app.M/2]; % switched M and N around around

            g_and_h = abs(block_center - true_center);
            
            % values for g and h
            app.bounding_box_gh = terms(best_term_idx).BoundingBox;
            app.g = g_and_h(1);
            app.h = g_and_h(2);
            disp("G: "+string(app.g)+" H: "+string(app.h));
        end
        function bw = binarize_compensated_plus1(app) % binarize spherical aberration 
            bw = imbinarize(real(app.holoCompensate)); %binarize the image
        end
        function [J] = Raul_CF_noTele_BAR_2d(app,fun,Cx,Cy) % Binarization Cost Fcn
            phi_spherical = fun(Cx,Cy);
            phase_mask = exp((-1j)*phi_spherical);
            corrected_image = app.holoCompensate .* phase_mask;
            phase = angle(corrected_image);
            phase = phase + pi; 
            ib = imbinarize(phase, 0.5);
            J = app.M*app.N - sum(ib(:));
        end
        function [J] = my_CF_noTele_BAR_2d(app,fun,Cx,Cy) % SD Cost Function
            phi_spherical = fun(Cx,Cy);
            phase_mask = exp((-1j)*phi_spherical);
            corrected_image = app.holoCompensate .* phase_mask;
            phase = angle(corrected_image);
            phase = phase + pi; 
            J = std(phase,[],'all');
        end
        function manual_recon(app) % Compensate spherical aberration
            if app.ChangeSignofCurvatureCheckBox.Value == 1
                phi_spherical_CxCy = @(Cx,Cy) -pi/app.lambda*((app.X-(app.g)+ 1).^2 *app.dx^2 /(2*Cx)+ (app.Y-(app.h)+1).^2 *app.dy^2/(2*Cy));
            else 
                phi_spherical_CxCy = @(Cx,Cy) pi/app.lambda*((app.X-(app.g)+ 1).^2 *app.dx^2 /(2*Cx)+ (app.Y-(app.h)+1).^2 *app.dy^2/(2*Cy));
            end 
            app.Cx = (app.N*app.dx)^2 / (app.lambda*2*app.m); % I switched M and N around
            app.Cy = (app.M*app.dy)^2 / (app.lambda*2*app.n);
            app.phi_spherical = phi_spherical_CxCy(app.Cx,app.Cy);
            app.phase_mask = exp((-1j)*app.phi_spherical);
            app.corrected_image = app.holoCompensate .* app.phase_mask;
        end
        function run_2d_minimization(app) % Automatic compensation of spherical aberration
            alg = string(app.AlgorithmDropDown.Value);
            cost = string(app.CostFunctionDropDown.Value);
            lb = @(C)-.5;ub = @(C).5; % +-C*50%
            lbx = lb(app.Cx); lby = lb(app.Cy); ubx = ub(app.Cx);uby = ub(app.Cy);
            phi_spherical_CxCy = @(Cx,Cy) pi/app.lambda*((app.X-(app.g)+ 1).^2 *app.dx^2 /(2*Cx)+ (app.Y-(app.h)+1).^2 *app.dy^2/(-2*Cy));
            rng default % For reproducibility
            app.seedCx = app.Cx;
            app.seedCy = app.Cy;
            x0 = [app.Cx, app.Cy];
            disp("seeds for Cx, Cy: "+string(x0))
            nvars = size(x0,2);
            init_pop_range_2d = @(Cx, Cy, lbx, ubx, lby, uby) [Cx+lbx*Cx, Cy+lby*Cy ; Cx+ubx*Cx, Cy+uby*Cy];
            % choose minimization algorithm
            if     alg == "GA+PS"
                    hybridopts = optimoptions('patternsearch','Display','final');
                    options = optimoptions(@ga,'HybridFcn', {@patternsearch,hybridopts},...
                        'InitialPopulationMatrix', x0,...
                        'InitialPopulationRange',  init_pop_range_2d(app.Cx, app.Cy, lbx, ubx, lby, uby), ...
                        'PopulationSize',15 ... % this speeds up convergence but not needed, default is 50 when nvars<=5
                        ...'SelectionFcn','selectionremainder');
                        );
            elseif  alg == "GA"
                    options = optimoptions(@ga,...
                        'InitialPopulationMatrix', x0,...
                        'InitialPopulationRange',  init_pop_range_2d(app.Cx, app.Cy, lbx, ubx, lby, uby), ...
                        'PopulationSize',15, ... % this speeds up convergence but not needed, default is 50 when nvars<=5
                        'SelectionFcn','selectionremainder');
            elseif alg == "PS"
                    options = optimoptions("patternsearch", "PollOrderAlgorithm", "Consecutive");
            elseif alg == "FMC"
                    options = optimoptions("fmincon");
            elseif alg == "FMU"
                    options = optimoptions("fminunc");
            elseif alg == "FSO"
                    options = optimoptions("fsolve");
            elseif  alg == "PTS"
                    options = optimoptions("paretosearch");
            elseif alg == "SA"
                    options = optimoptions("simulannealbnd");
            end  %options
            % choose cost function
            if cost == "Binarization"
                minfunc = @(t)Raul_CF_noTele_BAR_2d(app,phi_spherical_CxCy,t(1),t(2));
            elseif cost == "S.D."
                minfunc = @(t)my_CF_noTele_BAR_2d(app,phi_spherical_CxCy,t(1),t(2));
            end % select cost
            substr = alg.split('+');
            lb = [app.Cx+lbx*app.Cx; app.Cy+lby*app.Cy];
            ub = [app.Cx+ubx*app.Cx; app.Cy+uby*app.Cy];
            % run minimization
            if     substr(1) == "GA"
                    [out,~] = ga(minfunc,nvars,[],[],[],[],[],[],[],options);
            elseif alg == "PS"
                    [out,~] = patternsearch(minfunc,x0,[],[],[],[],lb,ub,[],options);
            elseif alg == "FMC"
                    [out,~] = fmincon(minfunc,x0,[],[],[],[],lb,ub,[],options);
            elseif alg == "FMM"
                    [out,~] = fminimax(minfunc,x0,[],[],[],[],lb,ub,[],options);
            elseif alg == "FMU"
                    [out,~] = fminunc(minfunc,x0,options);
            elseif alg == "FSO"
                    [out,~] = fsolve(minfunc,x0,options);
            elseif alg == "PTS"
                    [out,~] = paretosearch(minfunc,nvars,[],[],[],[],lb,ub,[],options);
            elseif alg == "PSW"
                   [out,~] = particleswarm(minfunc,x0,lb,ub,options);
            elseif alg == "SA"
                   [out,~] = simulannealbnd(minfunc,x0,lb,ub,options);
            elseif alg == "SGO"
                   [out,~] = surrogateopt(minfunc,lb,ub);
            end  % min
            % Reconstruct Hologram
            app.phi_spherical = phi_spherical_CxCy(out(1),out(2));
            app.phase_mask = exp((-1j)*app.phi_spherical);
            app.minimized_image = app.holoCompensate .* app.phase_mask;
            
            app.Cx = out(1);
            app.Cy = out(2);
        end   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            flag = InitializeDropDownMenu(app);
            if flag
                UpdateAll(app)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {614, 614};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: LoadHoloDropDown
        function LoadHoloDropDownValueChanged(app, event)
            UpdateAll(app)
        end

        % Button pushed function: Filter1termButton
        function Filter1termButtonPushed(app, event)
            % Get user to identify +1 term and use this for initial M and N
            r = drawrectangle(app.UIAxes_3,'Label','Crop','Color',[1 0 0]);
            app.crop = r.Position;
            app.plus1 = app.crop;
            InitializeMSlider(app); InitializeNSlider(app);
            addlistener(r,'MovingROI',@allevents);
            addlistener(r,'ROIMoved',@allevents);
            main(app)
            % Enable the following UI components since prerequisites are met
            app.FineTuneghButton.Enable = 1;
            app.FilterMNButton.Enable = 1;
            app.MSlider.Enable = 1; app.NSlider.Enable = 1;
            app.ChangeSignofCurvatureCheckBox.Enable = 1;
            app.EnableSwitch.Enable = 1;
            app.UnwrapButton.Enable = 1;
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'MovingROI'}
                        app.crop = (evt.CurrentPosition);
                        app.plus1 = app.crop;
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                    case{'ROIMoved'}
                        app.crop = (evt.CurrentPosition);
                        app.plus1 = app.crop;
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                end
            end
            
        end

        % Button pushed function: FilterMNButton
        function FilterMNButtonPushed(app, event)
            % Optional step to refine the dimenesions of +1 term seperate of crop
            r = drawrectangle(app.UIAxes_3,'Label','M & N');
            app.plus1 = r.Position;
            InitializeMSlider(app); InitializeNSlider(app);
            addlistener(r,'MovingROI',@allevents);
            addlistener(r,'ROIMoved',@allevents);
            main(app)
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'MovingROI'}
                        app.plus1 = evt.CurrentPosition;
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                    case{'ROIMoved'}
                        app.plus1 = (evt.CurrentPosition);
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Most other widgets call this automatically
            % this function. For Minimization this button must be manually
            % pressed. 
            if string(app.EnableSwitch.Value) == "On"
                app.BusyLamp.Color = [0,1,0];
                app.BusyLampLabel.Visible = 1;
                pause(.1); %this is to allow the lamp time to populate
                main(app,0,0,1)
                
                app.BusyLampLabel.Visible = 0;
                app.BusyLamp.Color = [1,0,0];
                
            else
                main(app)
            end
        end

        % Value changed function: FineTuneghButton
        function FineTuneghButtonValueChanged(app, event)
            value = app.FineTuneghButton.Value;
            if value == 1
                roi = drawpoint(app.UIAxes_2,'Label','Center');
                app.g = (roi.Position(1)-app.M/2 );
                app.h = (roi.Position(2)-app.N/2 );
                addlistener(roi,'MovingROI',@allevents);
                addlistener(roi,'ROIMoved',@allevents);
                main(app,0,1)
            end
            function allevents(src,evt)
                    evname = evt.EventName;
                    switch(evname)
                        case{'MovingROI'}
                            app.g = (evt.CurrentPosition(1) - app.M/2); 
                            app.h = (evt.CurrentPosition(2) - app.N/2);
                            main(app,0,1)
                        case{'ROIMoved'}
                            app.g = (evt.CurrentPosition(1) - app.M/2); 
                            app.h = (evt.CurrentPosition(2) - app.N/2);
                            main(app,0,1)
                    end
                end
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if string(value) == "On"
                app.RunButton.FontWeight = 'bold';
                app.RunButton.BackgroundColor = [1,0,0];
                app.CostFunctionLabel.Visible = 1;
                app.CostFunctionDropDown.Visible = 1;
                app.AlgorithmDropDownLabel.Visible = 1;
                app.AlgorithmDropDown.Visible = 1;
                app.BusyLamp.Visible = 1;
                app.RunButton.Visible = 1;
                if ~isempty(app.minimized_image)
                    UpdateUI_Axes(app,angle(app.minimized_image),...
                        app.UIAxes_4,app.cmap)
                end
            else
                app.RunButton.FontWeight = 'normal';
                app.RunButton.BackgroundColor = [.96,.96,.96];
                app.CostFunctionLabel.Visible = 0;
                app.CostFunctionDropDown.Visible = 0;
                app.AlgorithmDropDown.Visible = 0;
                app.AlgorithmDropDownLabel.Visible = 0;
                app.BusyLamp.Visible = 0;
                app.BusyLampLabel.Visible = 0;
                app.RunButton.Visible = 0;
                UpdateUI_Axes(app,angle(app.corrected_image),...
                        app.UIAxes_4,app.cmap)
            end
        end

        % Button pushed function: ExportReconButton
        function ExportReconButtonPushed(app, event)
            foldername = string(app.SaveFolderNameEditField.Value);
            filename = string(app.SaveFileNameEditField.Value);
            savename = foldername+"\"+filename;
            if isempty(dir(foldername))
                mkdir(string(app.SaveFolderNameEditField.Value))
            end
            export(app,savename)
        end

        % Value changed function: MSlider
        function MSliderValueChanged(app, event)
            app.plus1(3) = app.MSlider.Value;
            main(app)
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            app.plus1(4) = app.NSlider.Value;
            main(app)
        end

        % Value changing function: MSlider
        function MSliderValueChanging(app, event)
            app.plus1(3) = event.Value;
            main(app)
        end

        % Value changing function: NSlider
        function NSliderValueChanging(app, event)
            app.plus1(4) = event.Value;
            main(app)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.colormap = string(app.ColormapDropDown.Value);
            main(app)
        end

        % Value changed function: ChangeSignofCurvatureCheckBox
        function ChangeSignofCurvatureCheckBoxValueChanged(app, event)
            main(app,0,1)
        end

        % Button pushed function: UnwrapButton
        function UnwrapButtonPushed(app, event)
            if isempty(app.unwrap_image)
                app.BusyLamp.Color = [0,1,0];
                app.BusyLampLabel.Visible = 1;
                app.BusyLamp.Visible = 1;
                pause(.5);
                if app.EnableSwitch.Value == 1
                    app.unwrap_image = unwrap_phase(app.minimized_image);
                else
                    app.unwrap_image = unwrap_phase(app.corrected_image);
                end
                UpdateUI_Axes(app,angle(app.unwrap_image),...
                        app.UIAxes_4,app.cmap)
                app.BusyLamp.Visible = 0;
                app.BusyLampLabel.Visible = 0;
                app.BusyLamp.Color = [1,0,0];
                app.UnwrapButton.Text = "Rewrap";
            else 
                if app.UnwrapButton.Text == "Rewrap"
                    UpdateUI_Axes(app,angle(app.corrected_image),...
                        app.UIAxes_4,app.cmap)
                    app.UnwrapButton.Text = "Unwrap";
                elseif app.UnwrapButton.Text == "Unwrap"
                    UpdateUI_Axes(app,angle(app.unwrap_image),...
                        app.UIAxes_4,app.cmap)
                    app.UnwrapButton.Text = "Rewrap";
                end
            end
            function res_img = unwrap_phase(img)
                [Ny, Nx] = size(img);
            
                % get the reliability
                reliability = get_reliability(img); % (Ny,Nx)
            
                % get the edges
                [h_edges, v_edges] = get_edges(reliability); % (Ny,Nx) and (Ny,Nx)
            
                % combine all edges and sort it
                edges = [h_edges(:); v_edges(:)];
                edge_bound_idx = Ny * Nx; % if i <= edge_bound_idx, it is h_edges
                [~, edge_sort_idx] = sort(edges, 'descend');
            
                % get the indices of pixels adjacent to the edges
                idxs1 = mod(edge_sort_idx - 1, edge_bound_idx) + 1;
                idxs2 = idxs1 + 1 + (Ny - 1) .* (edge_sort_idx <= edge_bound_idx);
            
                % label the group
                group = reshape([1:numel(img)], Ny*Nx, 1);
                is_grouped = zeros(Ny*Nx,1);
                group_members = cell(Ny*Nx,1);
                for i = 1:size(is_grouped,1)
                    group_members{i} = i;
                end
                num_members_group = ones(Ny*Nx,1);
            
                % propagate the unwrapping
                res_img = img;
                num_nan = sum(isnan(edges)); % count how many nan-s and skip them
                for i = num_nan+1 : length(edge_sort_idx)
                    % get the indices of the adjacent pixels
                    idx1 = idxs1(i);
                    idx2 = idxs2(i);
            
                    % skip if they belong to the same group
                    if (group(idx1) == group(idx2)) continue; end
            
                    % idx1 should be ungrouped (swap if idx2 ungrouped and idx1 grouped)
                    % otherwise, activate the flag all_grouped.
                    % The group in idx1 must be smaller than in idx2. If initially
                    % group(idx1) is larger than group(idx2), then swap it.
                    all_grouped = 0;
                    if is_grouped(idx1)
                        if ~is_grouped(idx2)
                            idxt = idx1;
                            idx1 = idx2;
                            idx2 = idxt;
                        elseif num_members_group(group(idx1)) > num_members_group(group(idx2))
                            idxt = idx1;
                            idx1 = idx2;
                            idx2 = idxt;
                            all_grouped = 1;
                        else
                            all_grouped = 1;
                        end
                    end
            
                    % calculate how much we should add to the idx1 and group
                    dval = floor((res_img(idx2) - res_img(idx1) + pi) / (2*pi)) * 2*pi;
            
                    % which pixel should be changed
                    g1 = group(idx1);
                    g2 = group(idx2);
                    if all_grouped
                        pix_idxs = group_members{g1};
                    else
                        pix_idxs = idx1;
                    end
            
                    % add the pixel value
                    if dval ~= 0
                        res_img(pix_idxs) = res_img(pix_idxs) + dval;
                    end
            
                    % change the group
                    len_g1 = num_members_group(g1);
                    len_g2 = num_members_group(g2);
                    group_members{g2}(len_g2+1:len_g2+len_g1) = pix_idxs;
                    group(pix_idxs) = g2; % assign the pixels to the new group
                    num_members_group(g2) = num_members_group(g2) + len_g1;
            
                    % mark idx1 and idx2 as already being grouped
                    is_grouped(idx1) = 1;
                    is_grouped(idx2) = 1;
                end
            end
            
            function rel = get_reliability(img)
                rel = zeros(size(img));
            
                % get the shifted images (N-2, N-2)
                img_im1_jm1 = img(1:end-2, 1:end-2);
                img_i_jm1   = img(2:end-1, 1:end-2);
                img_ip1_jm1 = img(3:end  , 1:end-2);
                img_im1_j   = img(1:end-2, 2:end-1);
                img_i_j     = img(2:end-1, 2:end-1);
                img_ip1_j   = img(3:end  , 2:end-1);
                img_im1_jp1 = img(1:end-2, 3:end  );
                img_i_jp1   = img(2:end-1, 3:end  );
                img_ip1_jp1 = img(3:end  , 3:end  );
            
                % calculate the difference
                gamma = @(x) sign(x) .* mod(abs(x), pi);
                H  = gamma(img_im1_j   - img_i_j) - gamma(img_i_j - img_ip1_j  );
                V  = gamma(img_i_jm1   - img_i_j) - gamma(img_i_j - img_i_jp1  );
                D1 = gamma(img_im1_jm1 - img_i_j) - gamma(img_i_j - img_ip1_jp1);
                D2 = gamma(img_im1_jp1 - img_i_j) - gamma(img_i_j - img_ip1_jm1);
            
                % calculate the second derivative
                D = sqrt(H.*H + V.*V + D1.*D1 + D2.*D2);
            
                % assign the reliability as 1 / D
                rel(2:end-1, 2:end-1) = 1./D;
            
                % assign all nan's in rel with non-nan in img to 0
                % also assign the nan's in img to nan
                rel(isnan(rel) & ~isnan(img)) = 0;
                rel(isnan(img)) = nan;
            end
            
            function [h_edges, v_edges] = get_edges(rel)
                [Ny, Nx] = size(rel);
                h_edges = [rel(1:end, 2:end) + rel(1:end, 1:end-1), nan(Ny, 1)];
                v_edges = [rel(2:end, 1:end) + rel(1:end-1, 1:end); nan(1, Nx)];
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ReconstructTab = uitab(app.TabGroup);
            app.ReconstructTab.Title = 'Reconstruct';
            app.Step1LoadHologramLabel = uilabel(app.ReconstructTab);
            app.Step1LoadHologramLabel.Text = 'Step1: Load Hologram ';
            app.LoadHoloDropDown = uidropdown(app.ReconstructTab);
            app.LoadHoloDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadHoloDropDownValueChanged, true);
            app.LoadHoloDropDown.Tooltip = {'Loads in hologram file from same directory where this app is stored. Current supported file types include: .tif, .tiff'};
            app.WavelengthEditFieldLabel = uilabel(app.ReconstructTab);
            app.WavelengthEditFieldLabel.Text = 'Wavelength, λ:';
            app.WavelengthEditField = uieditfield(app.ReconstructTab, 'numeric');
            app.WavelengthEditField.Tooltip = {'Should be in the order of nanometeres (10e-7 m).'};
            app.WavelengthEditField.Value = 5.32e-07;
            app.PixelSizeXEditFieldLabel = uilabel(app.ReconstructTab);
            app.PixelSizeXEditFieldLabel.Text = 'Pixel Size, X=';
            app.PixelSizeXEditField = uieditfield(app.ReconstructTab, 'numeric');
            app.PixelSizeXEditField.Tooltip = {'Pixel size in X direction.'};
            app.PixelSizeXEditField.Value = 5.86e-06;
            app.YEditFieldLabel = uilabel(app.ReconstructTab);
            app.YEditFieldLabel.Text = 'Y=';
            app.YEditField = uieditfield(app.ReconstructTab, 'numeric');
            app.YEditField.Tooltip = {'Pixel size in Y direction.'};
            app.YEditField.Value = 5.86e-06;
            app.Filter1termButton = uibutton(app.ReconstructTab, 'push');
            app.Filter1termButton.ButtonPushedFcn = createCallbackFcn(app, @Filter1termButtonPushed, true);
            app.Filter1termButton.Tooltip = {'Press button to create a rectangular cropping region in plot 2 (Fourier Transform) to identify either of the twin image terms (±1 term). This rectangular crop region will be used to identify parameters M,N, P and Q. Refer to manual for a more detailed explanaition.'};
            app.Filter1termButton.Text = 'Filter ±1 term';
            app.FilterMNButton = uibutton(app.ReconstructTab, 'push');
            app.FilterMNButton.ButtonPushedFcn = createCallbackFcn(app, @FilterMNButtonPushed, true);
            app.FilterMNButton.Enable = 'off';
            app.FilterMNButton.Tooltip = {'Press button create a second rectangular crop to fine tune the size the lateral size of the ±1 term, independently of the "Filter ±1 term" button crop. Only the size of this rectangular crop is used, not it''s position. '};
            app.FilterMNButton.Text = 'Filter M&N';
            app.RunButton = uibutton(app.ReconstructTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Visible = 'off';
            app.RunButton.Tooltip = {'Press button to run minimization. (Warning: this typically takes some time, depending on your PC)'};
            app.RunButton.Text = 'Run';
            app.FineTuneghButton = uibutton(app.ReconstructTab, 'state');
            app.FineTuneghButton.ValueChangedFcn = createCallbackFcn(app, @FineTuneghButtonValueChanged, true);
            app.FineTuneghButton.Enable = 'off';
            app.FineTuneghButton.Tooltip = {'Press button to enable the manual identification of the center of the spherical aberration in plot 3 (Phase w/ Spherical Aberration), should fine tuning be necessary. '};
            app.FineTuneghButton.Text = 'Fine Tune g&h';
            app.EnableLabel = uilabel(app.ReconstructTab);
            app.EnableLabel.Tooltip = {'Switch to enable fine tuning via minimization algorithm.'};
            app.EnableLabel.Text = 'Enable:';
            app.EnableSwitch = uiswitch(app.ReconstructTab, 'toggle');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.EnableSwitch.Enable = 'off';
            app.EnableSwitch.Tooltip = {'After minimization is performed once, use switch to flip between manual and automatic reconstruction.'};
            app.ChangeSignofCurvatureCheckBox = uicheckbox(app.ReconstructTab);
            app.ChangeSignofCurvatureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeSignofCurvatureCheckBoxValueChanged, true);
            app.ChangeSignofCurvatureCheckBox.Enable = 'off';
            app.ChangeSignofCurvatureCheckBox.Tooltip = {'Changes the sign of the curvature. If checked sign is negative, otherwise positive. This change will be visibile in both plot 3 and plot 4.'};
            app.ChangeSignofCurvatureCheckBox.Text = 'Change Sign of Curvature';
            app.CostFunctionLabel = uilabel(app.ReconstructTab);
            app.CostFunctionLabel.Visible = 'off';
            app.CostFunctionLabel.Text = 'Cost Function:';
            app.CostFunctionDropDown = uidropdown(app.ReconstructTab);
            app.CostFunctionDropDown.Items = {'S.D.', 'Binarization'};
            app.CostFunctionDropDown.Visible = 'off';
            app.CostFunctionDropDown.Tooltip = {'Menu of cost functions for minimization algorithm (S.D. reccomended).'};
            app.CostFunctionDropDown.Value = 'S.D.';
            app.AlgorithmDropDownLabel = uilabel(app.ReconstructTab);
            app.AlgorithmDropDownLabel.Visible = 'off';
            app.AlgorithmDropDownLabel.Text = 'Algorithm:';
            app.AlgorithmDropDown = uidropdown(app.ReconstructTab);
            app.AlgorithmDropDown.Items = {'GA+PS', 'GA', 'PS', 'FMC', 'FMM', 'FMU', 'FSO', 'PTS', 'PSW', 'SA'};
            app.AlgorithmDropDown.Visible = 'off';
            app.AlgorithmDropDown.Tooltip = {'Menu of minimization algorithms ("GA+PS" reccomended. '};
            app.AlgorithmDropDown.Value = 'GA+PS';
            app.MSliderLabel = uilabel(app.ReconstructTab);
            app.MSliderLabel.Tooltip = {'Slider used to further fine tune the X size of the ±1 term. Alternative option to the "Fine tune M&N" button. Typically less frustrating...'};
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.ReconstructTab);
            app.MSlider.MajorTicks = [];
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.MSlider.MinorTicks = [];
            app.MSlider.Enable = 'off';
            app.MSlider.Value = 50;
            app.NSliderLabel = uilabel(app.ReconstructTab);
            app.NSliderLabel.Tooltip = {'Slider used to further fine tune the Y size of the ±1 term. Alternative option to the "Fine tune M&N" button. Typically less frustrating...'};
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.ReconstructTab);
            app.NSlider.MajorTicks = [];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.ValueChangingFcn = createCallbackFcn(app, @NSliderValueChanging, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Enable = 'off';
            app.NSlider.Value = 50;
            app.BusyLampLabel = uilabel(app.ReconstructTab);
            app.BusyLampLabel.Visible = 'off';
            app.BusyLampLabel.Text = 'Busy...';
            app.BusyLamp = uilamp(app.ReconstructTab);
            app.BusyLamp.Visible = 'off';
            app.BusyLamp.Tooltip = {'Green (something is running) or red (nothing is running).'};
            app.ColormapDropDown = uidropdown(app.ReconstructTab);
            app.ColormapDropDown.Items = {'gray', 'parula', 'hsv', 'bone', 'jet', 'hot', 'cool'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Tooltip = {'List of alternate colormaps that can be used to pseudo color plots 3 and 4. '};
            app.ColormapDropDown.Value = 'gray';
            app.UnwrapButton = uibutton(app.ReconstructTab, 'push');
            app.UnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @UnwrapButtonPushed, true);
            app.UnwrapButton.Enable = 'off';
            app.UnwrapButton.Tooltip = {'After unwrapping has been performed once, use button to toggle between Wrapped and Unwrapped phase reconstruction.'};
            app.UnwrapButton.Text = 'Unwrap';
            app.Label = uilabel(app.ReconstructTab);
            app.Label.Text = 'Step 2: Input Parameters';
            app.Label_2 = uilabel(app.ReconstructTab);
            app.Label_2.Text = 'Colormap: ';
            app.Label_3 = uilabel(app.ReconstructTab);
            app.Label_3.Text = 'Step 3: Manual Filtering (1rst Order)';
            app.Label_4 = uilabel(app.ReconstructTab);
            app.Label_4.Text = 'Step 4: Fine-Tuning (2nd-Order)';
            app.Label_5 = uilabel(app.ReconstructTab);
            app.Label_5.Tooltip = {'Seeded values obtained from previous steps. For minimization to be successful, starting seeds should be fairly close to providing a uniform background in plot 4 (Reconstruction). '};
            app.Label_5.Text = 'Step 5: Fine Tune w/ Minimization';
            app.Label_6 = uilabel(app.ReconstructTab);
            app.Label_6.Text = 'Step 6: Unwrap Phase';
            app.Label_7 = uilabel(app.ReconstructTab);
            app.Label_7.Text = 'Fine-Tuning M & N';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.ExportReconButton = uibutton(app.SaveTab, 'push');
            app.ExportReconButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReconButtonPushed, true);
            app.ExportReconButton.Tooltip = {'Export complete reconstruction with all parameters as a .mat file organized as a structure ("struct"). Name and location of this file is determined by previous Edit Fields (i.e., folderName/FileName.mat).'};
            app.ExportReconButton.Text = 'Export Recon';
            app.SaveFolderNameEditField = uieditfield(app.SaveTab, 'text');
            app.SaveFileNameEditField_2Label = uilabel(app.SaveTab);
            app.SaveFileNameEditField_2Label.Tooltip = {'Name of save file. Saved as .mat file. Defaults to having same name as orginal hologram fiile. '};
            app.SaveFileNameEditField_2Label.Text = 'Save File Name:';
            app.SaveFileNameEditField = uieditfield(app.SaveTab, 'text');
            app.SaveFolderNameLabel = uilabel(app.SaveTab);
            app.SaveFolderNameLabel.Tooltip = {'Edit field to enter the name of the save folder. Folder must be located in the same directory as this application, or a subdirectory. If folder does not already exists, creates a folder. Defaults to folder "save". '};
            app.SaveFolderNameLabel.Text = 'Save Folder Name:';
            app.MinimizedPhaseSwitchLabel = uilabel(app.SaveTab);
            app.MinimizedPhaseSwitchLabel.Tooltip = {'Save phase reconstruction obtained by Minimization algorithm?'};
            app.MinimizedPhaseSwitchLabel.Text = 'Minimized Phase';
            app.MinimizedPhaseSwitch = uiswitch(app.SaveTab, 'slider');
            app.MinimizedPhaseSwitch.Value = 'On';
            app.UnwrappedPhaseSwitchLabel = uilabel(app.SaveTab);
            app.UnwrappedPhaseSwitchLabel.Tooltip = {'Save reconstruction obtained by Unwrapping algorithm?'};
            app.UnwrappedPhaseSwitchLabel.Text = 'Unwrapped Phase';
            app.UnwrappedPhaseSwitch = uiswitch(app.SaveTab, 'slider');
            app.UnwrappedPhaseSwitch.Value = 'On';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Phase w/ Spherical Aberration')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Hologram')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Fourier Transform')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Reconstruction')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NonTeleReconApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end