classdef NonTeleReconApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ReconstructTab                 matlab.ui.container.Tab
        LoadHoloLabel                  matlab.ui.control.Label
        LoadHoloDropDown               matlab.ui.control.DropDown
        LambdaEditFieldLabel           matlab.ui.control.Label
        LambdaEditField                matlab.ui.control.NumericEditField
        PixelSizeXEditFieldLabel       matlab.ui.control.Label
        PixelSizeXEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        GetCropButton                  matlab.ui.control.Button
        GetMNButton                    matlab.ui.control.Button
        ReconstructButton              matlab.ui.control.Button
        ManualGHButton                 matlab.ui.control.StateButton
        EnableMinimizationSwitchLabel  matlab.ui.control.Label
        EnableMinimizationSwitch       matlab.ui.control.ToggleSwitch
        InvertmapCheckBox              matlab.ui.control.CheckBox
        CostFunctionDropDownLabel      matlab.ui.control.Label
        CostFunctionDropDown           matlab.ui.control.DropDown
        MinAlgDropDownLabel            matlab.ui.control.Label
        MinAlgDropDown                 matlab.ui.control.DropDown
        MSliderLabel                   matlab.ui.control.Label
        MSlider                        matlab.ui.control.Slider
        NSliderLabel                   matlab.ui.control.Label
        NSlider                        matlab.ui.control.Slider
        BusyLampLabel                  matlab.ui.control.Label
        BusyLamp                       matlab.ui.control.Lamp
        ColormapDropDown               matlab.ui.control.DropDown
        UnwrapButton                   matlab.ui.control.Button
        SaveTab                        matlab.ui.container.Tab
        ExportReconButton              matlab.ui.control.Button
        SaveFolderNameEditField        matlab.ui.control.EditField
        SaveFileNameEditField_2Label   matlab.ui.control.Label
        SaveFileNameEditField          matlab.ui.control.EditField
        SaveFolderNameLabel            matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public) %properties corresponding to reconstruction algorithm
      lambda double {mustBeNonnegative} % wavelength
      k double {mustBeNumeric}          % wave number
      dx {mustBeNumeric}                % pixel size in x
      dy {mustBeNumeric}                % pixel size in y
      g {mustBeNumeric}                 % x coordinate of spherical center
      h {mustBeNumeric}                 % y coordinate of spherical center
      m {mustBeNumeric}                 % x coordinate size of +1 term
      n {mustBeNumeric}                 % y coordinate size of +1 term
      p {mustBeNumeric}                 % x coordinate position of +1 term
      q {mustBeNumeric}                 % y coordinate position of +1 term
      seedCx double{mustBeNumeric}      % value of Cx prior to minimization
      seedCy double{mustBeNumeric}      % value of Cy prior to minimization
      Cx double{mustBeNumeric}          % radius of curvature in x
      Cy double{mustBeNumeric}          % radius of curvature in y
      M {mustBeNumeric}                 % size(holo) in X
      N {mustBeNumeric}                 % size(holo) in Y
      X                                 % meshgrid X
      Y                                 % meshgrid Y
      filename                          % name of read file 
      phi_spherical                     % spherical aberration created using Anon func and before putting in exp(-j*phi)
      phase_mask                        % phase mask created w/ phi_spherical that is multiplied by holocompensate
      corrected_image                   % image corrected (i.e. Min/Kemper) before minimization
      holoCompensate                    % +1 term center and IFT'd. use angle(holoCompensate) to show
      holo                              % hologram
      ft_holo                           % fourier transform of holo
      crop                              % rect selection of +1 to be used for reconstruction
      plus_coor                         % coordinates of +1 term
      plus1                             % rect of +1 term
      bounding_box_gh                   % rect of spherical aberration used to find G & H
      min_alg                           % minimization algorithm used (GA+PS, PS, SA, etc)
      cost_fn                           % cost function used for minimization (BIN or SD) 
      min_time                          % time it took for reconstruction
      cmap                              % colormap used for figures
    end
    methods (Access = private) %methods having to do with UI
        function flag = InitializeDropDownMenu(app) % function that populates Drop down menu
            f = [dir("*.tiff");dir("*.tif")]; % currently only search tif and tiff extensions but more can be added
            flag = ~isempty(f); 
            if flag % only attempts to populate drop down if folder has images
                items = cell(1,length(f));
                for i = 1:length(f)
                    items{i} = (f(i).name);
                end
                app.LoadHoloDropDown.Items = items;
                app.LoadHoloDropDown.Value = f(1).name; % defaults to first value
            end
        end
        function UpdateAll(app) % Updates Ui for new input hologram
            InitializeProperties(app,string(app.LoadHoloDropDown.Value),...
                app.LambdaEditField.Value, app.PixelSizeXEditField.Value,...
                app.YEditField.Value);
            UpdateUI_Axes1(app)
            UpdateUI_Axes3(app)
        end
        function UpdateUI_Axes1(app) % Updates Axes 1 with hologram
            imagesc(app.UIAxes, app.holo),...
            colormap(app.UIAxes,gray),axis(app.UIAxes, 'tight');
        end 
        function UpdateUI_Axes3(app) % Updates Axes 3 with FT of hologram
            imagesc(app.UIAxes_3, log(abs(app.ft_holo)).^2),...
            colormap(app.UIAxes_3,gray),axis(app.UIAxes_3, 'tight');
        end
        function InitializeMSlider(app) % Initialize M Slider
            m = app.plus1(3); 
            r = .5; % 50% range + and - m
            app.MSlider.Limits = [m-r*m, m+r*m];
            app.MSlider.Value = m;
        end
        function InitializeNSlider(app) % Initialize N Slider
            n = app.plus1(4);
            r = .5; % 50% range + and - n
            app.NSlider.Limits =[n-r*n, n+r*n];
            app.NSlider.Value = n;
        end
    end
    methods (Access = private)%methods having to do with recon alg
        function main(app,varargin) % 6 step reconstruction algorithm for non-telecentric algortihm
            get_plus1_manually(app) % get size and postion of +1 term by user
            filter_center_plus1(app) % compensates linear tilt w/ reference wave
            if app.ManualGHButton.Value == 0 % automatically finds the center of the spherical aberration
                bw = binarize_compensated_plus1(app);
                get_g_and_h(app, bw);
            end
            manual_recon(app); % Uses parameters to compensate spherical aberration
            if nargin == 1 % update both axis 2 and 4
                imagesc(app.UIAxes_2,angle(app.holoCompensate)),... % plot phase w/ spherical aberration
                        colormap(app.UIAxes_2,app.cmap),axis(app.UIAxes_2, 'tight');
                imagesc(app.UIAxes_4,angle(app.corrected_image)),... % plot compensted phase
                        colormap(app.UIAxes_4,app.cmap),axis(app.UIAxes_4, 'tight');
            end
            if nargin>1 && varargin{1} == 1 % only update axis 2
                imagesc(app.UIAxes_2,angle(app.holoCompensate)),...
                    colormap(app.UIAxes_2,app.cmap),axis(app.UIAxes_2, 'tight');
            end
            if nargin>2 && varargin{2} == 1 % only update axis 4
                imagesc(app.UIAxes_4,angle(app.corrected_image)),...
                    colormap(app.UIAxes_4,app.cmap),axis(app.UIAxes_4, 'tight');
            end
            if nargin>3 && varargin{3} == 1 % update axis 4 after minimization
                tic
                run_2d_minimization(app)
                app.min_time = toc;
                disp(app.min_time)
                imagesc(app.UIAxes_4,angle(app.corrected_image)),...
                        colormap(app.UIAxes_4,app.cmap),axis(app.UIAxes_4, 'tight');
            end            
        end
        function export(app,savefile) % save output structure
              saved = [];
              saved.lambda = app.lambda;
              saved.k = app.k;
              saved.dx = app.dx;
              saved.dy = app.dy;
              saved.g = app.g;
              saved.h = app.h;
              saved.m = app.m;
              saved.n = app.n;
              saved.p = app.p;
              saved.q = app.q;
              saved.seedCx = app.seedCx;
              saved.seedCy = app.seedCy;
              saved.Cx = app.Cx;
              saved.Cy = app.Cy;
              saved.M = app.M;
              saved.N = app.N;
              saved.X = app.X;
              saved.Y = app.Y;
              saved.filename = app.filename;
              saved.phi_spherical = app.phi_spherical;
              saved.phase_mask = app.phase_mask;
              saved.corrected_image = app.corrected_image;
              saved.holoCompensate = app.holoCompensate;
              saved.holo = app.holo;
              saved.ft_holo= app.ft_holo;
              saved.crop= app.crop;
              saved.plus_coor= app.plus_coor;
              saved.plus1= app.plus1;
              saved.bounding_box_gh= app.bounding_box_gh;
              saved.savefile = savefile;
              save(savefile,"saved")
        end
        function InitializeProperties(app,varargin) % initialize most properties of algorithm
           app.filename = varargin{1}; % second input is filename
           app.cmap = app.ColormapDropDown.Value; % get cmap from dropdown
           savename = string(app.filename).split("."); % drop extension for savename 
           savename = savename(1)+".mat"; % append new extension
           app.SaveFileNameEditField.Value = savename; % set save name
           app.SaveFolderNameEditField.Value = "save"; % set default save foldler
           app.lambda = varargin{2}; % get wavelength from third input
           app.k = 2*pi/app.lambda; % calculate wavenumber
           app.dx = varargin{3};app.dy = varargin{4} ;% inputs 4 and 5 are pixel dimensions
           app.holo = double(imread(app.filename)); % read image selected by drop down menu
           app.holo = app.holo(:,:,1); % only use first channel (grayscale images)
           if nargin>5
                app.crop = varargin{5}; % if there is a 6th input, use as image crop
                app.holo = imcrop(app.holo,app.crop);
           end
           [app.M,app.N] = size(app.holo); % sensor size    
           % get meshgrid for pixel index list. meshgrid take columns and then rows
           [app.X,app.Y] = meshgrid(-app.N/2+1:app.N/2,-app.M/2+1:app.M/2); %switched M(row) and N(col)
           app.ft_holo = FT(app); % get fourier transform
           app.plus1 = [0,0,0,0]; % use as defualt values for +1 term
        end
        function ft = FT(app) % get fourier transform of hologram 
            ft = fftshift(fft2(fftshift(app.holo)));
        end
        function get_plus1_manually(app) % parse out user crop input to useful information
            % crop(1)and(2) is top left coordinate and crop(3)and(4) is dimensions
            app.plus_coor = [app.crop(1)+app.crop(3)/2, app.crop(2)+app.crop(4)/2]; 
            dc_coor = [app.M/2, app.N/2]; % DC coor is half the sensor size
            p_and_q = abs(app.plus_coor - dc_coor); % distance from center to +1 term 
            % values for p,q,m and n (min and kemper paper)
            app.m = app.plus1(3)/2 ;%
            app.n = app.plus1(4)/2 ;%
            app.p = p_and_q(1);
            app.q = p_and_q(2);
            disp("P: "+string(app.p)+" Q: "+string(app.q));
            disp("M: "+string(app.m)+" N: "+string(app.n));
        end
        function filter_center_plus1(app) % compensate tilt aberration
            Filter = zeros(app.M,app.N);
            warning('off','all')
            Filter((app.plus_coor(2) - app.n) :(app.plus_coor(2)+ app.n),...
            (app.plus_coor(1) - app.m) :(app.plus_coor(1)+ app.m))=1;
            FT_FilteringH = app.ft_holo.*Filter;
            
            ThetaXN = asin((app.N/2 - app.plus_coor(1))*app.lambda/(app.N * app.dx));
            ThetaYM = asin((app.M/2 - app.plus_coor(2))*app.lambda/(app.M * app.dy));
            Reference = exp(1i*app.k*(sin(ThetaXN)*app.X*app.dx + sin(ThetaYM)*app.Y*app.dy));

            holo_filtered = fftshift(ifft2(fftshift(FT_FilteringH)));
            app.holoCompensate = holo_filtered.*Reference;
            
        end
        function get_g_and_h(app, bw) % get center coordinates for spherical aberration
            cc = bwconncomp(bw,4);

            numPixels = cellfun(@numel,cc.PixelIdxList);
            num_of_terms = 3;
            idx_list = zeros(num_of_terms,1);


            for i = 1:num_of_terms
                [~,idx] = max(numPixels);
                idx_list(i) = idx;
                numPixels(idx) = 0;
            end

            for i = 1:length(cc.PixelIdxList)
                if ~(i == idx_list(1) || i == idx_list(2) || i == idx_list(3))
                    bw(cc.PixelIdxList{i}) = 0;
                end
            end

            terms = regionprops(bw,'all');
            [~,best_term_idx] = min([terms.Eccentricity]);
            best_term = terms(best_term_idx).BoundingBox;
            block_center = [best_term(1)+best_term(3)/2, best_term(2)+best_term(4)/2];
            true_center = [app.N/2, app.M/2]; % switched M and N around around

            g_and_h = abs(block_center - true_center);
            
            % values for g and h
            app.bounding_box_gh = terms(best_term_idx).BoundingBox;
            app.g = g_and_h(1);
            app.h = g_and_h(2);
            disp("G: "+string(app.g)+" H: "+string(app.h));
        end
        function bw = binarize_compensated_plus1(app) % binarize spherical aberration 
            bw = imbinarize(real(app.holoCompensate)); %binarize the image
        end
        function [J] = Raul_CF_noTele_BAR_2d(app,fun,Cx,Cy) % Binarization Cost Fcn
            phi_spherical = fun(Cx,Cy);
            phase_mask = exp((-1j)*phi_spherical);
            corrected_image = app.holoCompensate .* phase_mask;
            phase = angle(corrected_image);
            phase = phase + pi; 
            ib = imbinarize(phase, 0.5);
            J = app.M*app.N - sum(ib(:));
        end
        function [J] = my_CF_noTele_BAR_2d(app,fun,Cx,Cy) % SD Cost Function
            phi_spherical = fun(Cx,Cy);
            phase_mask = exp((-1j)*phi_spherical);
            corrected_image = app.holoCompensate .* phase_mask;
            phase = angle(corrected_image);
            phase = phase + pi; 
            J = std(phase,[],'all');
        end
        function manual_recon(app) % Compensate spherical aberration
            if app.InvertmapCheckBox.Value == 1
                phi_spherical_CxCy = @(Cx,Cy) -pi/app.lambda*((app.X-(app.g)+ 1).^2 *app.dx^2 /(2*Cx)+ (app.Y-(app.h)+1).^2 *app.dy^2/(2*Cy));
            else 
                phi_spherical_CxCy = @(Cx,Cy) pi/app.lambda*((app.X-(app.g)+ 1).^2 *app.dx^2 /(2*Cx)+ (app.Y-(app.h)+1).^2 *app.dy^2/(2*Cy));
            end 
            app.Cx = (app.N*app.dx)^2 / (app.lambda*2*app.m); % I switched M and N around
            app.Cy = (app.M*app.dy)^2 / (app.lambda*2*app.n);
            app.phi_spherical = phi_spherical_CxCy(app.Cx,app.Cy);
            app.phase_mask = exp((-1j)*app.phi_spherical);
            app.corrected_image = app.holoCompensate .* app.phase_mask;
        end
        function run_2d_minimization(app) % Automatic compensation of spherical aberration
            alg = string(app.MinAlgDropDown.Value);
            cost = string(app.CostFunctionDropDown.Value);
            lb = @(C)-.5;ub = @(C).5; % +-C*50%
            lbx = lb(app.Cx); lby = lb(app.Cy); ubx = ub(app.Cx);uby = ub(app.Cy);
            phi_spherical_CxCy = @(Cx,Cy) pi/app.lambda*((app.X-(app.g)+ 1).^2 *app.dx^2 /(2*Cx)+ (app.Y-(app.h)+1).^2 *app.dy^2/(-2*Cy));
            rng default % For reproducibility
            app.seedCx = app.Cx;
            app.seedCy = app.Cy;
            x0 = [app.Cx, app.Cy];
            disp("seeds for Cx, Cy: "+string(x0))
            nvars = size(x0,2);
            init_pop_range_2d = @(Cx, Cy, lbx, ubx, lby, uby) [Cx+lbx*Cx, Cy+lby*Cy ; Cx+ubx*Cx, Cy+uby*Cy];
            % choose minimization algorithm
            if     alg == "GA+PS"
                    hybridopts = optimoptions('patternsearch','Display','final');
                    options = optimoptions(@ga,'HybridFcn', {@patternsearch,hybridopts},...
                        'InitialPopulationMatrix', x0,...
                        'InitialPopulationRange',  init_pop_range_2d(app.Cx, app.Cy, lbx, ubx, lby, uby), ...
                        'PopulationSize',15 ... % this speeds up convergence but not needed, default is 50 when nvars<=5
                        ...'SelectionFcn','selectionremainder');
                        );
            elseif  alg == "GA"
                    options = optimoptions(@ga,...
                        'InitialPopulationMatrix', x0,...
                        'InitialPopulationRange',  init_pop_range_2d(app.Cx, app.Cy, lbx, ubx, lby, uby), ...
                        'PopulationSize',15, ... % this speeds up convergence but not needed, default is 50 when nvars<=5
                        'SelectionFcn','selectionremainder');
            elseif alg == "PS"
                    options = optimoptions("patternsearch", "PollOrderAlgorithm", "Consecutive");
            elseif alg == "FMC"
                    options = optimoptions("fmincon");
            elseif alg == "FMU"
                    options = optimoptions("fminunc");
            elseif alg == "FSO"
                    options = optimoptions("fsolve");
            elseif  alg == "PTS"
                    options = optimoptions("paretosearch");
            elseif alg == "SA"
                    options = optimoptions("simulannealbnd");
            end  %options
            % choose cost function
            if cost == "Binarization"
                minfunc = @(t)Raul_CF_noTele_BAR_2d(app,phi_spherical_CxCy,t(1),t(2));
            elseif cost == "S.D."
                minfunc = @(t)my_CF_noTele_BAR_2d(app,phi_spherical_CxCy,t(1),t(2));
            end % select cost
            substr = alg.split('+');
            lb = [app.Cx+lbx*app.Cx; app.Cy+lby*app.Cy];
            ub = [app.Cx+ubx*app.Cx; app.Cy+uby*app.Cy];
            % run minimization
            if     substr(1) == "GA"
                    [out,~] = ga(minfunc,nvars,[],[],[],[],[],[],[],options);
            elseif alg == "PS"
                    [out,~] = patternsearch(minfunc,x0,[],[],[],[],lb,ub,[],options);
            elseif alg == "FMC"
                    [out,~] = fmincon(minfunc,x0,[],[],[],[],lb,ub,[],options);
            elseif alg == "FMM"
                    [out,~] = fminimax(minfunc,x0,[],[],[],[],lb,ub,[],options);
            elseif alg == "FMU"
                    [out,~] = fminunc(minfunc,x0,options);
            elseif alg == "FSO"
                    [out,~] = fsolve(minfunc,x0,options);
            elseif alg == "PTS"
                    [out,~] = paretosearch(minfunc,nvars,[],[],[],[],lb,ub,[],options);
            elseif alg == "PSW"
                   [out,~] = particleswarm(minfunc,x0,lb,ub,options);
            elseif alg == "SA"
                   [out,~] = simulannealbnd(minfunc,x0,lb,ub,options);
            elseif alg == "SGO"
                   [out,~] = surrogateopt(minfunc,lb,ub);
            end  % min
            % Reconstruct Hologram
            app.phi_spherical = phi_spherical_CxCy(out(1),out(2));
            app.phase_mask = exp((-1j)*app.phi_spherical);
            app.corrected_image = app.holoCompensate .* app.phase_mask;
            
            app.Cx = out(1);
            app.Cy = out(2);
        end   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            flag = InitializeDropDownMenu(app);
            if flag
                UpdateAll(app)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {499, 499};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: LoadHoloDropDown
        function LoadHoloDropDownValueChanged(app, event)
            UpdateAll(app)
        end

        % Button pushed function: GetCropButton
        function GetCropButtonPushed(app, event)
            % Get user to identify +1 term and use this for initial M and N
            r = drawrectangle(app.UIAxes_3,'Label','Crop','Color',[1 0 0]);
            app.crop = r.Position;
            app.plus1 = app.crop;
            InitializeMSlider(app); InitializeNSlider(app);
            addlistener(r,'MovingROI',@allevents);
            addlistener(r,'ROIMoved',@allevents);
            main(app)
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'MovingROI'}
                        app.crop = (evt.CurrentPosition);
                        app.plus1 = app.crop;
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                    case{'ROIMoved'}
                        app.crop = (evt.CurrentPosition);
                        app.plus1 = app.crop;
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                end
            end
            
        end

        % Button pushed function: GetMNButton
        function GetMNButtonPushed(app, event)
            % Optional step to refine the dimenesions of +1 term seperate of crop
            r = drawrectangle(app.UIAxes_3,'Label','M & N');
            app.plus1 = r.Position;
            InitializeMSlider(app); InitializeNSlider(app);
            addlistener(r,'MovingROI',@allevents);
            addlistener(r,'ROIMoved',@allevents);
            main(app)
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'MovingROI'}
                        app.plus1 = evt.CurrentPosition;
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                    case{'ROIMoved'}
                        app.plus1 = (evt.CurrentPosition);
                        InitializeMSlider(app); InitializeNSlider(app);
                        main(app)
                end
            end
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            % Reconstruction button so user may manually signal for
            % reconstruction. Most other widgets call this automatically
            % this function. For Minimization this button must be manually
            % pressed. 
            if string(app.EnableMinimizationSwitch.Value) == "On"
                app.BusyLamp.Color = [0,1,0];
                app.BusyLampLabel.Visible = 1;
                pause(.5);
                main(app,0,0,1)
                app.EnableMinimizationSwitch.Value = 'Off';
                app.ReconstructButton.FontWeight = 'normal';
                app.ReconstructButton.BackgroundColor = [.96,.96,.96];
                app.CostFunctionDropDown.Visible = 0;
                app.MinAlgDropDown.Visible = 0;
                app.BusyLamp.Visible = 0;
                app.BusyLampLabel.Visible = 0;
                app.BusyLamp.Color = [1,0,0];
                app.CostFunctionDropDownLabel.Visible = 0;
                app.MinAlgDropDownLabel.Visible = 0;
            else
                main(app)
            end
        end

        % Value changed function: ManualGHButton
        function ManualGHButtonValueChanged(app, event)
            value = app.ManualGHButton.Value;
            if value == 1
                roi = drawpoint(app.UIAxes_2,'Label','Center');
                app.g = (roi.Position(1)-app.M/2 );
                app.h = (roi.Position(2)-app.N/2 );
                addlistener(roi,'MovingROI',@allevents);
                addlistener(roi,'ROIMoved',@allevents);
                main(app,0,1)
            end
            function allevents(src,evt)
                    evname = evt.EventName;
                    switch(evname)
                        case{'MovingROI'}
                            app.g = (evt.CurrentPosition(1) - app.M/2); 
                            app.h = (evt.CurrentPosition(2) - app.N/2);
                            main(app,0,1)
                        case{'ROIMoved'}
                            app.g = (evt.CurrentPosition(1) - app.M/2); 
                            app.h = (evt.CurrentPosition(2) - app.N/2);
                            main(app,0,1)
                    end
                end
        end

        % Value changed function: EnableMinimizationSwitch
        function EnableMinimizationSwitchValueChanged(app, event)
            value = app.EnableMinimizationSwitch.Value;
            if string(value) == "On"
                app.ReconstructButton.FontWeight = 'bold';
                app.ReconstructButton.BackgroundColor = [1,0,0];
                app.CostFunctionDropDownLabel.Visible = 1;
                app.CostFunctionDropDown.Visible = 1;
                app.MinAlgDropDownLabel.Visible = 1;
                app.MinAlgDropDown.Visible = 1;
                app.BusyLamp.Visible = 1;
            else
                app.ReconstructButton.FontWeight = 'normal';
                app.ReconstructButton.BackgroundColor = [.96,.96,.96];
                app.CostFunctionDropDownLabel.Visible = 0;
                app.CostFunctionDropDown.Visible = 0;
                app.MinAlgDropDown.Visible = 0;
                app.MinAlgDropDownLabel.Visible = 0;
                app.BusyLamp.Visible = 0;
            end
        end

        % Button pushed function: ExportReconButton
        function ExportReconButtonPushed(app, event)
            foldername = string(app.SaveFolderNameEditField.Value);
            filename = string(app.SaveFileNameEditField.Value);
            savename = foldername+"\"+filename;
            if isempty(dir(foldername))
                mkdir(string(app.SaveFolderNameEditField.Value))
            end
            export(app,savename)
        end

        % Value changed function: MSlider
        function MSliderValueChanged(app, event)
            app.plus1(3) = app.MSlider.Value;
            main(app)
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            app.plus1(4) = app.NSlider.Value;
            main(app)
        end

        % Value changing function: MSlider
        function MSliderValueChanging(app, event)
            app.plus1(3) = event.Value;
            main(app)
        end

        % Value changing function: NSlider
        function NSliderValueChanging(app, event)
            app.plus1(4) = event.Value;
            main(app)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.colormap = string(app.ColormapDropDown.Value);
            main(app)
        end

        % Value changed function: InvertmapCheckBox
        function InvertmapCheckBoxValueChanged(app, event)
            main(app,0,1)
        end

        % Button pushed function: UnwrapButton
        function UnwrapButtonPushed(app, event)
            app.BusyLamp.Color = [0,1,0];
            app.BusyLampLabel.Visible = 1;
            app.BusyLamp.Visible = 1;
            pause(.5);
            app.corrected_image = unwrap_phase(app.corrected_image);
            imagesc(app.UIAxes_4,angle(app.corrected_image)),...
                    colormap(app.UIAxes_4,app.colormap),axis(app.UIAxes_4, 'tight');
            app.BusyLamp.Visible = 0;
            app.BusyLampLabel.Visible = 0;
            app.BusyLamp.Color = [1,0,0];
            function res_img = unwrap_phase(img)
                [Ny, Nx] = size(img);
            
                % get the reliability
                reliability = get_reliability(img); % (Ny,Nx)
            
                % get the edges
                [h_edges, v_edges] = get_edges(reliability); % (Ny,Nx) and (Ny,Nx)
            
                % combine all edges and sort it
                edges = [h_edges(:); v_edges(:)];
                edge_bound_idx = Ny * Nx; % if i <= edge_bound_idx, it is h_edges
                [~, edge_sort_idx] = sort(edges, 'descend');
            
                % get the indices of pixels adjacent to the edges
                idxs1 = mod(edge_sort_idx - 1, edge_bound_idx) + 1;
                idxs2 = idxs1 + 1 + (Ny - 1) .* (edge_sort_idx <= edge_bound_idx);
            
                % label the group
                group = reshape([1:numel(img)], Ny*Nx, 1);
                is_grouped = zeros(Ny*Nx,1);
                group_members = cell(Ny*Nx,1);
                for i = 1:size(is_grouped,1)
                    group_members{i} = i;
                end
                num_members_group = ones(Ny*Nx,1);
            
                % propagate the unwrapping
                res_img = img;
                num_nan = sum(isnan(edges)); % count how many nan-s and skip them
                for i = num_nan+1 : length(edge_sort_idx)
                    % get the indices of the adjacent pixels
                    idx1 = idxs1(i);
                    idx2 = idxs2(i);
            
                    % skip if they belong to the same group
                    if (group(idx1) == group(idx2)) continue; end
            
                    % idx1 should be ungrouped (swap if idx2 ungrouped and idx1 grouped)
                    % otherwise, activate the flag all_grouped.
                    % The group in idx1 must be smaller than in idx2. If initially
                    % group(idx1) is larger than group(idx2), then swap it.
                    all_grouped = 0;
                    if is_grouped(idx1)
                        if ~is_grouped(idx2)
                            idxt = idx1;
                            idx1 = idx2;
                            idx2 = idxt;
                        elseif num_members_group(group(idx1)) > num_members_group(group(idx2))
                            idxt = idx1;
                            idx1 = idx2;
                            idx2 = idxt;
                            all_grouped = 1;
                        else
                            all_grouped = 1;
                        end
                    end
            
                    % calculate how much we should add to the idx1 and group
                    dval = floor((res_img(idx2) - res_img(idx1) + pi) / (2*pi)) * 2*pi;
            
                    % which pixel should be changed
                    g1 = group(idx1);
                    g2 = group(idx2);
                    if all_grouped
                        pix_idxs = group_members{g1};
                    else
                        pix_idxs = idx1;
                    end
            
                    % add the pixel value
                    if dval ~= 0
                        res_img(pix_idxs) = res_img(pix_idxs) + dval;
                    end
            
                    % change the group
                    len_g1 = num_members_group(g1);
                    len_g2 = num_members_group(g2);
                    group_members{g2}(len_g2+1:len_g2+len_g1) = pix_idxs;
                    group(pix_idxs) = g2; % assign the pixels to the new group
                    num_members_group(g2) = num_members_group(g2) + len_g1;
            
                    % mark idx1 and idx2 as already being grouped
                    is_grouped(idx1) = 1;
                    is_grouped(idx2) = 1;
                end
            end
            
            function rel = get_reliability(img)
                rel = zeros(size(img));
            
                % get the shifted images (N-2, N-2)
                img_im1_jm1 = img(1:end-2, 1:end-2);
                img_i_jm1   = img(2:end-1, 1:end-2);
                img_ip1_jm1 = img(3:end  , 1:end-2);
                img_im1_j   = img(1:end-2, 2:end-1);
                img_i_j     = img(2:end-1, 2:end-1);
                img_ip1_j   = img(3:end  , 2:end-1);
                img_im1_jp1 = img(1:end-2, 3:end  );
                img_i_jp1   = img(2:end-1, 3:end  );
                img_ip1_jp1 = img(3:end  , 3:end  );
            
                % calculate the difference
                gamma = @(x) sign(x) .* mod(abs(x), pi);
                H  = gamma(img_im1_j   - img_i_j) - gamma(img_i_j - img_ip1_j  );
                V  = gamma(img_i_jm1   - img_i_j) - gamma(img_i_j - img_i_jp1  );
                D1 = gamma(img_im1_jm1 - img_i_j) - gamma(img_i_j - img_ip1_jp1);
                D2 = gamma(img_im1_jp1 - img_i_j) - gamma(img_i_j - img_ip1_jm1);
            
                % calculate the second derivative
                D = sqrt(H.*H + V.*V + D1.*D1 + D2.*D2);
            
                % assign the reliability as 1 / D
                rel(2:end-1, 2:end-1) = 1./D;
            
                % assign all nan's in rel with non-nan in img to 0
                % also assign the nan's in img to nan
                rel(isnan(rel) & ~isnan(img)) = 0;
                rel(isnan(img)) = nan;
            end
            
            function [h_edges, v_edges] = get_edges(rel)
                [Ny, Nx] = size(rel);
                h_edges = [rel(1:end, 2:end) + rel(1:end, 1:end-1), nan(Ny, 1)];
                v_edges = [rel(2:end, 1:end) + rel(1:end-1, 1:end); nan(1, Nx)];
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ReconstructTab = uitab(app.TabGroup);
            app.ReconstructTab.Title = 'Reconstruct';
            app.LoadHoloLabel = uilabel(app.ReconstructTab);
            app.LoadHoloLabel.Text = 'Load Holo:';
            app.LoadHoloDropDown = uidropdown(app.ReconstructTab);
            app.LoadHoloDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadHoloDropDownValueChanged, true);
            app.LambdaEditFieldLabel = uilabel(app.ReconstructTab);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.ReconstructTab, 'numeric');
            app.LambdaEditField.Value = 5.32e-07;
            app.PixelSizeXEditFieldLabel = uilabel(app.ReconstructTab);
            app.PixelSizeXEditFieldLabel.Text = 'Pixel Size, X=';
            app.PixelSizeXEditField = uieditfield(app.ReconstructTab, 'numeric');
            app.PixelSizeXEditField.Value = 5.86e-06;
            app.YEditFieldLabel = uilabel(app.ReconstructTab);
            app.YEditFieldLabel.Text = 'Y=';
            app.YEditField = uieditfield(app.ReconstructTab, 'numeric');
            app.YEditField.Value = 5.86e-06;
            app.GetCropButton = uibutton(app.ReconstructTab, 'push');
            app.GetCropButton.ButtonPushedFcn = createCallbackFcn(app, @GetCropButtonPushed, true);
            app.GetCropButton.Text = 'Get Crop';
            app.GetMNButton = uibutton(app.ReconstructTab, 'push');
            app.GetMNButton.ButtonPushedFcn = createCallbackFcn(app, @GetMNButtonPushed, true);
            app.GetMNButton.Text = 'Get M&N';
            app.ReconstructButton = uibutton(app.ReconstructTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.ManualGHButton = uibutton(app.ReconstructTab, 'state');
            app.ManualGHButton.ValueChangedFcn = createCallbackFcn(app, @ManualGHButtonValueChanged, true);
            app.ManualGHButton.Text = 'Manual G&H';
            app.EnableMinimizationSwitchLabel = uilabel(app.ReconstructTab);
            app.EnableMinimizationSwitchLabel.Text = 'Enable Minimization';
            app.EnableMinimizationSwitch = uiswitch(app.ReconstructTab, 'toggle');
            app.EnableMinimizationSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableMinimizationSwitchValueChanged, true);
            app.InvertmapCheckBox = uicheckbox(app.ReconstructTab);
            app.InvertmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertmapCheckBoxValueChanged, true);
            app.InvertmapCheckBox.Text = 'Invert map';
            app.CostFunctionDropDownLabel = uilabel(app.ReconstructTab);
            app.CostFunctionDropDownLabel.Visible = 'off';
            app.CostFunctionDropDownLabel.Text = 'Cost Function';
            app.CostFunctionDropDown = uidropdown(app.ReconstructTab);
            app.CostFunctionDropDown.Items = {'S.D.', 'Binarization'};
            app.CostFunctionDropDown.Visible = 'off';
            app.CostFunctionDropDown.Value = 'S.D.';
            app.MinAlgDropDownLabel = uilabel(app.ReconstructTab);
            app.MinAlgDropDownLabel.Visible = 'off';
            app.MinAlgDropDownLabel.Text = 'Min. Alg.';
            app.MinAlgDropDown = uidropdown(app.ReconstructTab);
            app.MinAlgDropDown.Items = {'GA+PS', 'GA', 'PS', 'FMC', 'FMM', 'FMU', 'FSO', 'PTS', 'PSW', 'SA'};
            app.MinAlgDropDown.Visible = 'off';
            app.MinAlgDropDown.Value = 'GA+PS';
            app.MSliderLabel = uilabel(app.ReconstructTab);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.ReconstructTab);
            app.MSlider.MajorTicks = [];
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.MSlider.MinorTicks = [];
            app.MSlider.Value = 50;
            app.NSliderLabel = uilabel(app.ReconstructTab);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.ReconstructTab);
            app.NSlider.MajorTicks = [];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.ValueChangingFcn = createCallbackFcn(app, @NSliderValueChanging, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Value = 50;
            app.BusyLampLabel = uilabel(app.ReconstructTab);
            app.BusyLampLabel.Visible = 'off';
            app.BusyLampLabel.Text = 'Busy...';
            app.BusyLamp = uilamp(app.ReconstructTab);
            app.BusyLamp.Visible = 'off';
            app.ColormapDropDown = uidropdown(app.ReconstructTab);
            app.ColormapDropDown.Items = {'gray', 'parula', 'hsv', 'bone', 'jet', 'hot', 'cool'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.UnwrapButton = uibutton(app.ReconstructTab, 'push');
            app.UnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @UnwrapButtonPushed, true);
            app.UnwrapButton.Text = 'Unwrap';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.ExportReconButton = uibutton(app.SaveTab, 'push');
            app.ExportReconButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReconButtonPushed, true);
            app.ExportReconButton.Text = 'Export Recon';
            app.SaveFolderNameEditField = uieditfield(app.SaveTab, 'text');
            app.SaveFileNameEditField_2Label = uilabel(app.SaveTab);
            app.SaveFileNameEditField_2Label.Text = 'Save File Name:';
            app.SaveFileNameEditField = uieditfield(app.SaveTab, 'text');
            app.SaveFolderNameLabel = uilabel(app.SaveTab);
            app.SaveFolderNameLabel.Text = 'Save Folder Name:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Phase w/ Spherical Aberration')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            zlabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Hologram')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Fourier Transform')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            zlabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Reconstruction')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            zlabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NonTeleReconApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end